classdef view_interference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        FreeFixedSlider               matlab.ui.control.Slider
        FreeFixedSliderLabel          matlab.ui.control.Label
        TranslateButton               matlab.ui.control.Button
        y_translation                 matlab.ui.control.NumericEditField
        ytranslationEditFieldLabel    matlab.ui.control.Label
        x_translation                 matlab.ui.control.NumericEditField
        xtranslationEditFieldLabel    matlab.ui.control.Label
        misorientation                matlab.ui.control.NumericEditField
        MisorientationAngleEditFieldLabel  matlab.ui.control.Label
        fourier_degree                matlab.ui.control.Slider
        FourierDegreeSliderLabel      matlab.ui.control.Label
        MorePreciseLabel              matlab.ui.control.Label
        LessPreciseLabel              matlab.ui.control.Label
        ComputationalParametersLabel  matlab.ui.control.Label
        FreeLatticeControlsLabel      matlab.ui.control.Label
        tick_spacing_denominator      matlab.ui.control.Slider
        PrecisionLabel                matlab.ui.control.Label
        axis_size                     matlab.ui.control.NumericEditField
        AxisSizeLabel                 matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        axes_fixed                    matlab.ui.control.UIAxes
        axes_free                     matlab.ui.control.UIAxes
        axes_super                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        X % x-indices
        Y % y-indices
        view_angle
        camera_position
        tick_marks
        fixed_lattice
        free_lattice
        lattice_spacing
        tick_spacing
        last_center
        movement_bool
        metalattice_spacing
        misorientation_rad
        super_lattice
        x_o
        y_o

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.y_o.Value=0;
            app.x_o.Value=0;
            app.tick_marks.Value=4;
            app.last_center = [0,0];
            app.movement_bool=1;
            app.lattice_spacing.Value=1;
            disp(app.lattice_spacing);

            app.tick_spacing.Value=app.lattice_spacing.Value/app.tick_spacing_denominator.Value;
            app.fourier_degree.Value=1;


            app.misorientation.Value=15;
            app.misorientation_rad.Value=app.misorientation.Value*pi/180;
         
            [app.X,app.Y]=get_plane_indices(app.lattice_spacing.Value, app.tick_spacing.Value, app.axis_size.Value);

            app.fixed_lattice=get_lattice(0,app.lattice_spacing.Value, 0, 0, app.X,app.Y, app.fourier_degree.Value);
%             app.fixed_lattice=get_lattice(0,app.lattice_spacing.Value, 0, 0, app.X,app.Y);

            plot_component_lattice(app.axes_fixed, app.fixed_lattice, app.X, app.Y);
            xlim(app.axes_fixed, [-5,5]);
            ylim(app.axes_fixed, [-5,5]);
            
            xlim(app.axes_free, [-5,5]);
            ylim(app.axes_free, [-5,5]);

            view(app.axes_super,0,90);

            zlabel(app.axes_super, 'Superimposed distance to nearest vertex');
            app.UpdateButtonPushed;
            
          
        end

        % Callback function: FreeFixedSlider, axes_fixed, axes_super, 
        % ...and 1 other component
        function UpdateButtonPushed(app, event)

            
            app.metalattice_spacing.Value=(1+cos(app.misorientation_rad.Value))/(sqrt(3)*(sin(app.misorientation_rad.Value)));
            disp('ml spacing magnitude')
            disp(app.metalattice_spacing.Value)
            metalattice_vector = app.metalattice_spacing.Value*[cos(app.misorientation_rad.Value/2), sin(app.misorientation_rad.Value/2)];
            
            app.fixed_lattice=get_lattice(0,app.lattice_spacing.Value, 0, 0, app.X,app.Y, app.fourier_degree.Value);
            app.free_lattice=get_lattice(app.misorientation_rad.Value, app.lattice_spacing.Value, app.x_o.Value, app.y_o.Value, app.X, app.Y,app.fourier_degree.Value);
            
            plot_component_lattice(app.axes_free, app.free_lattice, app.X, app.Y);
            net_displacement=[app.x_o.Value,app.y_o.Value];
    
            app.super_lattice = abs(app.FreeFixedSlider.Value*app.fixed_lattice+app.free_lattice)/10;
            peak=max(app.super_lattice(:));
%             disp('peak')
%             disp(peak)
            
            set(gcf, 'renderer', 'zbuffer');
            plot_superlattice_interference(app.axes_super, app.X, app.Y, app.super_lattice, peak);
%             plot_metalattice_axes();
            
            hold(app.axes_super, "on")
 
            translation_angle = atan(app.y_translation.Value/app.x_translation.Value)+pi/2+app.misorientation_rad.Value/2;
            ortho_to_translation = atan(app.y_translation.Value/app.x_translation.Value)+pi/2-app.misorientation_rad.Value/2;

            free_lattice_vector = [app.x_translation.Value, app.y_translation.Value];
            free_lattice_vector = free_lattice_vector./norm(free_lattice_vector);

%             
            ortho_to_ml_v=[cos(ortho_to_translation), sin(ortho_to_translation)];


            quiver3(app.axes_super, 0,0,peak, cos(translation_angle), sin(translation_angle), 0, app.axis_size.Value,"filled", '-y', LineWidth=2)
            quiver3(app.axes_super, 0,0,peak,free_lattice_vector(1), free_lattice_vector(2), 0, app.axis_size.Value, "filled", '-r', LineWidth=2);
%             quiver3(app.axes_super, 0,0,peak,ortho_to_ml_v(1), ortho_to_ml_v(2), 0, app.axis_size.Value, "filled", '-g', LineWidth=2);
            quiver3(app.axes_super, 0,0,peak,metalattice_vector(1), metalattice_vector(2), 0, "filled", '-g', LineWidth=2);

            
            hold(app.axes_super, "off");
            
            hold(app.axes_free, 'on')
            quiver(app.axes_free, 0,0,free_lattice_vector(1), free_lattice_vector(2), "filled", '-r', LineWidth=2);
            

            hold(app.axes_free, 'off')
%             view(app.axes_super, 90+app.misorientation.Value/2, 90);


        end

        % Value changed function: axis_size, tick_spacing_denominator
        function axis_sizeValueChanged(app, event)

            app.tick_spacing.Value = app.lattice_spacing.Value/app.tick_spacing_denominator.Value;
            [app.X,app.Y]=get_plane_indices(app.lattice_spacing.Value, app.tick_spacing.Value, app.axis_size.Value);
            app.fixed_lattice=get_lattice(0,app.lattice_spacing.Value, 0, 0, app.X,app.Y, app.fourier_degree.Value);
            plot_component_lattice(app.axes_fixed, app.fixed_lattice, app.X, app.Y);
            app.UpdateButtonPushed;
        end

        % Callback function
        function fix_centerValueChanged(app, event)
            if app.fix_center=='Off'
                disp('last center value')
                disp(app.last_center)
                app.last_center=[0, 0];
                app.UpdateButtonPushed;
            end
            
        end

        % Value changed function: misorientation
        function misorientationValueChanged(app, event)
            value = app.misorientation.Value;
            app.misorientation_rad.Value=pi/180*value;
            app.UpdateButtonPushed;
        end

        % Callback function
        function VECTORBUTTONButtonPushed(app, event)
            disp('end of X');
            disp(size(app.X));
            disp(app.X(end));
            disp('end of Y');
            k=boundary(app.X(:), app.Y(:));
            disp(k);
            disp('size of k')
            disp(size(k))
            boundary_len = size(app.super_lattice(1));
        
            U_bound = k(1:boundary_len);
            R_bound = k(boundary_len+1:2*boundary_len);
            D_bound = k(2*boundary_len+1:3*boundary_len);
            L_bound = k(3*boundary_len+1:4*boundary_len);
            U_flux = 0;
            R_flux=0;
            D_flux=0;
            L_flux=0;
            step=0.1*app.metalattice_spacing.Value;
            total_distance=0;
            while total_distance<4*app.metalattice_spacing.Value
                U_flux = U_flux + sum(app.super_lattice(U_bound));
                R_flux = R_flux + sum(app.super_lattice(R_bound));
                D_flux = D_flux + sum(app.super_lattice(D_bound));
                L_flux = L_flux + sum(app.super_lattice(L_bound));
                app.y_o.Value = app.y_o.Value + step;
                total_distance = total_distance + step;
                app.UpdateButtonPushed;
            end
            disp('R, U')
            disp([R_flux, U_flux]);
            disp('L,U')
            disp([L_flux, U_flux]);
            disp('huh')
            disp([L_flux-R_flux, D_flux-U_flux])
            disp(atan((L_flux-R_flux)/(D_flux-U_flux))*180/pi);
%             quiver3(app.axes_super, 0,0,2,R_flux, U_flux,2);

        end

        % Button pushed function: TranslateButton
        function TranslateButtonPushed(app, event)
            app.x_o.Value=app.x_o.Value+app.x_translation.Value;
            app.y_o.Value=app.y_o.Value+app.y_translation.Value;
            app.UpdateButtonPushed;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {541, 541};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {197, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AxisSizeLabel = uilabel(app.LeftPanel);
            app.AxisSizeLabel.Text = 'Axis Size';
            app.axis_size = uieditfield(app.LeftPanel, 'numeric');
            app.axis_size.ValueChangedFcn = createCallbackFcn(app, @axis_sizeValueChanged, true);
            app.axis_size.Value = 10;
            app.PrecisionLabel = uilabel(app.LeftPanel);
            app.PrecisionLabel.Text = 'Ticks per lattice spacing';
            app.tick_spacing_denominator = uislider(app.LeftPanel);
            app.tick_spacing_denominator.Limits = [1 20];
            app.tick_spacing_denominator.ValueChangedFcn = createCallbackFcn(app, @axis_sizeValueChanged, true);
            app.tick_spacing_denominator.Value = 8;
            app.FreeLatticeControlsLabel = uilabel(app.LeftPanel);
            app.FreeLatticeControlsLabel.Text = 'Free Lattice Controls';
            app.ComputationalParametersLabel = uilabel(app.LeftPanel);
            app.ComputationalParametersLabel.Text = 'Computational Parameters';
            app.LessPreciseLabel = uilabel(app.LeftPanel);
            app.LessPreciseLabel.Text = 'Less Precise';
            app.MorePreciseLabel = uilabel(app.LeftPanel);
            app.MorePreciseLabel.Text = 'More Precise';
            app.FourierDegreeSliderLabel = uilabel(app.LeftPanel);
            app.FourierDegreeSliderLabel.Text = 'Fourier Degree';
            app.fourier_degree = uislider(app.LeftPanel);
            app.fourier_degree.Limits = [1 7];
            app.fourier_degree.MajorTicks = [1 2 3 4 5 6 7];
            app.fourier_degree.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.fourier_degree.MinorTicks = [];
            app.fourier_degree.Value = 4;
            app.MisorientationAngleEditFieldLabel = uilabel(app.LeftPanel);
            app.MisorientationAngleEditFieldLabel.Text = 'Misorientation Angle';
            app.misorientation = uieditfield(app.LeftPanel, 'numeric');
            app.misorientation.ValueChangedFcn = createCallbackFcn(app, @misorientationValueChanged, true);
            app.xtranslationEditFieldLabel = uilabel(app.LeftPanel);
            app.xtranslationEditFieldLabel.Text = 'x-translation';
            app.x_translation = uieditfield(app.LeftPanel, 'numeric');
            app.x_translation.Value = 0.1;
            app.ytranslationEditFieldLabel = uilabel(app.LeftPanel);
            app.ytranslationEditFieldLabel.Text = 'y-translation';
            app.y_translation = uieditfield(app.LeftPanel, 'numeric');
            app.y_translation.Value = 0.1;
            app.TranslateButton = uibutton(app.LeftPanel, 'push');
            app.TranslateButton.ButtonPushedFcn = createCallbackFcn(app, @TranslateButtonPushed, true);
            app.TranslateButton.Text = 'Translate';
            app.FreeFixedSliderLabel = uilabel(app.LeftPanel);
            app.FreeFixedSliderLabel.Text = 'Free:Fixed';
            app.FreeFixedSlider = uislider(app.LeftPanel);
            app.FreeFixedSlider.Limits = [1 8];
            app.FreeFixedSlider.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.FreeFixedSlider.Value = 4;
            app.RightPanel = uipanel(app.GridLayout);
            app.axes_super = uiaxes(app.RightPanel);
            title(app.axes_super, 'Superlattice - Top Down')
            xlabel(app.axes_super, 'X')
            ylabel(app.axes_super, 'Y')
            zlabel(app.axes_super, 'Z')
            app.axes_super.ButtonDownFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            colormap(app.axes_super, 'spring')
            app.axes_free = uiaxes(app.RightPanel);
            title(app.axes_free, 'Free Lattice')
            zlabel(app.axes_free, 'Z')
            app.axes_free.XTick = [];
            app.axes_free.YTick = [];
            colormap(app.axes_free, 'turbo')
            app.axes_fixed = uiaxes(app.RightPanel);
            title(app.axes_fixed, 'Fixed Lattice')
            zlabel(app.axes_fixed, 'Z')
            app.axes_fixed.XTick = [];
            app.axes_fixed.YTick = [];
            app.axes_fixed.ButtonDownFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            colormap(app.axes_fixed, 'turbo')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = view_interference

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end